classdef AEX_collision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_3                       matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        ImageIntervalsEditField       matlab.ui.control.NumericEditField
        ImageIntervalsEditFieldLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        FindVelocityLabel             matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        AEXAdvancedAerospaceExplorerLabel  matlab.ui.control.Label
        SelectimagesButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        num % Description
        img_range % Description
        tarea % Description
        f_area % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            AEX
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: SelectimagesButton
        function SelectimagesButtonPushed(app, event)
            app.tarea = 0.0;
            coll_time = 0;
            img_temp = 0;
            [file,path] = uigetfile('*.png',...
   'Select More than One Image', ...
   'MultiSelect', 'on');
            figure(app.UIFigure);
            app.num = size(file);
            app.num = app.num(2);
            try
                for i = 1:app.num
                    I = imread(string(append(path,file(i))));
                    I_resize = imresize(I,0.1);
                    I_gray = rgb2gray(I_resize);
                    I_bin = imbinarize(I_gray,'adaptive');
                    I_fill = imfill(I_bin,'holes');
                    I_filt = bwareafilt(I_fill,[2000 Inf]);
                    if i == 1
                        img_1 = I_filt;
                    elseif i == app.num
                        img_2 = I_filt;
                    end
                    all_properties = regionprops('table',I_filt,'all');
                    area_(i) = all_properties.Area;
                    area_;
                    if i > 1
                        app.tarea = app.tarea + (area_(i)/area_(i-1));
                    end
                end
                app.Label_2.Text = 'Results:';
                app.Label_2.Visible = 'on';
                app.Image2.Visible = 'on';
                img_temp =  img_2 - img_1;
                imwrite(img_temp,'temp.png');
                app.Image2.ImageSource = 'temp.png';
                app.img_range = length(I_filt);
                app.f_area = area_(app.num);
                app.ImageIntervalsEditFieldLabel.Visible = 'on';
                app.ImageIntervalsEditField.Visible = 'on';
                app.CalculateButton.Visible = 'on';
            catch
                figure(app.UIFigure);
                f = msgbox('Please select more than one image', 'Error','error');
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.ImageIntervalsEditField.Value <= 0
                f = msgbox('Invalid Value', 'Error','error');
            else
                img_area = app.img_range^2;
                img_int = app.ImageIntervalsEditField.Value;
                tscale = 1 + (app.tarea/(img_int*app.num));
                coll_time = log(img_area*0.9/app.f_area)/log(tscale);
                str = ['Scalling ratio: ',newline,num2str(tscale),'x per second',newline,newline,'Expected collsion in: ',newline,num2str(coll_time),' seconds']; 
                app.Label_3.Text = str;
                app.Label_3.Visible = 'on';
                if coll_time < 10
                    f = msgbox('Immediately Evacuate!!!', 'Error','error');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectimagesButton = uibutton(app.UIFigure, 'push');
            app.SelectimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimagesButtonPushed, true);
            app.SelectimagesButton.Text = 'Select images';
            app.AEXAdvancedAerospaceExplorerLabel = uilabel(app.UIFigure);
            app.AEXAdvancedAerospaceExplorerLabel.Text = 'AEX: Advanced Aerospace Explorer';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.FindVelocityLabel = uilabel(app.UIFigure);
            app.FindVelocityLabel.Text = 'Find Collision';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'asteroid.png';
            app.ImageIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.ImageIntervalsEditFieldLabel.Visible = 'off';
            app.ImageIntervalsEditFieldLabel.Text = 'Image Interval(s):';
            app.ImageIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageIntervalsEditField.Visible = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Visible = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AEX_collision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end