classdef AEX_velocity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculateButton               matlab.ui.control.Button
        ImageIntervalsEditField       matlab.ui.control.NumericEditField
        ImageIntervalsEditFieldLabel  matlab.ui.control.Label
        DepthofFieldKmEditField       matlab.ui.control.NumericEditField
        DepthofFieldKMLabel           matlab.ui.control.Label
        FieldofVisionEditField        matlab.ui.control.NumericEditField
        FieldofVisionLabel            matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        FindVelocityLabel             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        AEXAdvancedAerospaceExplorerLabel  matlab.ui.control.Label
        SelectimagesButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        img_range % Description
        num % Description
        dist_x % Description
        dist_y % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            AEX
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: SelectimagesButton
        function SelectimagesButtonPushed(app, event)

            app.dist_x = 0.0;
            app.dist_y = 0.0;
            img_temp = 0;
            [file,path] = uigetfile('*.png',...
   'Select More than One Image', ...
   'MultiSelect', 'on');
            figure(app.UIFigure);
            app.num = size(file);
            app.num = app.num(2);
            

            try
                for i = 1:app.num
                    I = imread(string(append(path,file(i))));
                    I_resize = imresize(I,0.1);
                    I_gray = rgb2gray(I_resize);
                    I_bin = imbinarize(I_gray,'adaptive');
                    I_fill = imfill(I_bin,'holes');
                    I_filt = bwareafilt(I_fill,[2000 Inf]);
                    all_properties = regionprops('table',I_filt,'all');
                    centers = all_properties.Centroid;
                    coor = all_properties.Centroid;
                    coor_x(i) = coor(1);
                    coor_y(i) = coor(2);
                    if i > 1
                        app.dist_x = app.dist_x + (coor_x(i)-coor_x(i-1));
                        app.dist_y = app.dist_y + (coor_y(i-1)-coor_y(i));
                    end
                    img_temp = img_temp + I_filt;
                end
                app.Label_2.Text = 'Results:';
                app.Image2.Visible = 'on';
                imwrite(img_temp,'temp.png');
                app.Image2.ImageSource = 'temp.png';
                app.img_range = length(I_filt);
                app.FieldofVisionLabel.Visible = 'on';
                app.FieldofVisionEditField.Visible = 'on';
                app.DepthofFieldKMLabel.Visible = 'on';
                app.DepthofFieldKmEditField.Visible = 'on';
                app.ImageIntervalsEditFieldLabel.Visible = 'on';
                app.ImageIntervalsEditField.Visible = 'on';
                app.CalculateButton.Visible = 'on';

            catch
                figure(app.UIFigure);
                f = msgbox('Please select more than one image', 'Error','error');
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.FieldofVisionEditField.Value <= 0 | app.FieldofVisionEditField.Value >= 180 | app.DepthofFieldKmEditField.Value <= 0 | app.ImageIntervalsEditField.Value <= 0
                f = msgbox('Invalid Value', 'Error','error');
            else
                img_area = app.img_range^2;
                fov = app.FieldofVisionEditField.Value;
                dof = app.DepthofFieldKmEditField.Value;
                img_int = app.ImageIntervalsEditField.Value;
                ori_range = 2 * dof * tand(fov/2);
                ori_img_ratio = ori_range/app.img_range;
                velo_x = app.dist_x/(img_int*app.num)*ori_img_ratio;
                velo_y = app.dist_y/(img_int*app.num)*ori_img_ratio;
                res_velo = sqrt(velo_x^2 + velo_y^2);
                res_angle = atand(abs(velo_y/velo_x));
                if velo_x < 0 & velo_y < 0
                    res_dir = "S-E";
                elseif velo_x > 0 & velo_y > 0
                    res_dir = "N-W";
                elseif velo_x < 0 & velo_y > 0
                    res_dir = "N-E";
                elseif velo_x > 0 & velo_y < 0
                    res_dir = "S-W";
                else
                    res_dir = "";
                end
                str = ['X-axis Velocity: ',newline,num2str(velo_x),' Km/s',newline,newline,'Y-axis Velocity: ',newline,num2str(velo_y),' Km/s',newline];
                if res_dir ~= ""
                    res_str = strjoin(['Resultant Velocity: ',newline,num2str(res_velo),' Km/s ',num2str(res_angle),'° ',res_dir],'');
                    str = [str res_str];
                end
                app.Label.Text = str;
                app.Label.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectimagesButton = uibutton(app.UIFigure, 'push');
            app.SelectimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimagesButtonPushed, true);
            app.SelectimagesButton.Text = 'Select images';
            app.AEXAdvancedAerospaceExplorerLabel = uilabel(app.UIFigure);
            app.AEXAdvancedAerospaceExplorerLabel.Text = 'AEX: Advanced Aerospace Explorer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'car.png';
            app.FindVelocityLabel = uilabel(app.UIFigure);
            app.FindVelocityLabel.Text = 'Find Velocity';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.FieldofVisionLabel = uilabel(app.UIFigure);
            app.FieldofVisionLabel.Visible = 'off';
            app.FieldofVisionLabel.Text = 'Field of Vision(°):';
            app.FieldofVisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.FieldofVisionEditField.Visible = 'off';
            app.DepthofFieldKMLabel = uilabel(app.UIFigure);
            app.DepthofFieldKMLabel.Visible = 'off';
            app.DepthofFieldKMLabel.Text = 'Depth of Field(Km):';
            app.DepthofFieldKmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthofFieldKmEditField.Visible = 'off';
            app.ImageIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.ImageIntervalsEditFieldLabel.Visible = 'off';
            app.ImageIntervalsEditFieldLabel.Text = 'Image Interval(s):';
            app.ImageIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageIntervalsEditField.Visible = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Visible = 'off';
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AEX_velocity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end